classdef paleoDataSearch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        DataDownloadButton   matlab.ui.control.Button
        StudyLocation        matlab.ui.container.Panel
        GotostudypageButton  matlab.ui.control.Button
        StudyPageTextArea    matlab.ui.control.TextArea
        Label_StudyPage      matlab.ui.control.Label
        CitationTextArea     matlab.ui.control.TextArea
        Label_Citation       matlab.ui.control.Label
        OriginatorTextArea   matlab.ui.control.TextArea
        Label_Originator     matlab.ui.control.Label
        SearchResults        matlab.ui.control.ListBox
        Label_DataType_10    matlab.ui.control.Label
        TabGroup             matlab.ui.container.TabGroup
        GeneralSearchTab     matlab.ui.container.Tab
        ClearButton          matlab.ui.control.Button
        LonMax               matlab.ui.control.NumericEditField
        LatMax               matlab.ui.control.NumericEditField
        MapAxes              matlab.ui.container.Panel
        Label_Latitude       matlab.ui.control.Label
        Label_Longitude      matlab.ui.control.Label
        LonMin               matlab.ui.control.NumericEditField
        Label_MaxLon         matlab.ui.control.Label
        Label_MinLon         matlab.ui.control.Label
        LatMin               matlab.ui.control.NumericEditField
        Label_MaxLat         matlab.ui.control.Label
        Label_MinLat         matlab.ui.control.Label
        SearchButton         matlab.ui.control.Button
        APIURL               matlab.ui.control.TextArea
        DataTypeList         matlab.ui.control.ListBox
        Label_DataTypeTip    matlab.ui.control.Label
        Label_DataType       matlab.ui.control.Label
        Label_APIURL         matlab.ui.control.Label
        COLORSLabel          matlab.ui.control.Label
        Label_14             matlab.ui.control.Label
        Label_12             matlab.ui.control.Label
        Label_11             matlab.ui.control.Label
        Label_10             matlab.ui.control.Label
        Label_9              matlab.ui.control.Label
        Label_8              matlab.ui.control.Label
        HighlightLabel       matlab.ui.control.Label
        H3BackgroundLabel    matlab.ui.control.Label
        ButtonLabel          matlab.ui.control.Label
        ForegroundLabel      matlab.ui.control.Label
        H2BackgroundLabel    matlab.ui.control.Label
        H1BackgroundLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        api_base % Base URL for the API
        map_axes % Map axes
        map_axes2 % Seond map axes
        RobinsonProjectedCRS % Map projection
        MercatorProjectedCRS % Map projection
        api_full_json % Full JSON struct
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %
            % Populate dataType list
            %
            dataTypeIds = get_dataTypeId();
            dataTypes = fieldnames(dataTypeIds);
             %General Search
               app.DataTypeList.Items=dataTypes;
               app.DataTypeList.Multiselect='on';
             %Advanced Search
               app.AdvancedDataTypeList.Items=dataTypes;
               app.AdvancedDataTypeList.Multiselect='on';
               app.InvestigatorList.Items={'N/A'};

            %
            % Get API URL
            %
            app.api_base = app.APIURL.Value;

            %
            % Create map axes
            %
            % Study Distributions
            land = readgeotable("landareas.shp");
            app.map_axes = mapaxes(app.MapAxes,NextPlot="add",Color=[1 1 1]);
            geoplot(app.map_axes,land,FaceColor=[0.85 0.85 0.85],EdgeColor=[0.13 0.13 0.13]);
            app.RobinsonProjectedCRS = projcrs(54030,Authority="ESRI");
            app.map_axes.ProjectedCRS = app.RobinsonProjectedCRS;
            % Study Locations
            app.map_axes2 = mapaxes(app.StudyLocation,NextPlot="add",Color=[1 1 1]);
            geoplot(app.map_axes2,land,FaceColor=[0.85 0.85 0.85],EdgeColor=[0.13 0.13 0.13]);
            app.MercatorProjectedCRS = projcrs(3395,Authority="EPSG");
            app.map_axes2.ProjectedCRS = app.MercatorProjectedCRS;
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            %
            % Update API URL
            %
            dataTypeIds = get_dataTypeId();
            fields = fieldnames(dataTypeIds);
            user_selections = app.DataTypeList.Value;
            idx = contains(fields,user_selections);
            % Data Types
            if isscalar(user_selections)
                id = dataTypeIds.(fields{idx});
                update_api = [char(string(app.api_base)) '&dataTypeId=' num2str(id)];
                app.APIURL.Value = update_api;
            else
                ids = cell2mat(struct2cell(dataTypeIds));
                ids = ids(idx);
                link = '%7C';
                update_api = [char(string(app.api_base)) '&dataTypeId=' num2str(ids(1))];
                for id = 2:length(ids)
                  update_api = [update_api link num2str(ids(id))];
                end
                app.APIURL.Value = update_api;
            end
            % Latitude/Longitude
            latMin = app.LatMin.Value;
            latMax = app.LatMax.Value;
            lonMin = app.LonMin.Value;
            lonMax = app.LonMax.Value;
            if latMin ~= -90
             app.APIURL.Value = [char(app.APIURL.Value) '&minLat=' num2str(latMin)];
            end
            if latMax ~= 90
             app.APIURL.Value = [char(app.APIURL.Value) '&maxLat=' num2str(latMax)];
            end
            if lonMin ~= -180
             app.APIURL.Value = [char(app.APIURL.Value) '&minLon=' num2str(lonMin)];
            end
            if lonMax ~= 180
             app.APIURL.Value = [char(app.APIURL.Value) '&maxLon=' num2str(lonMax)];
            end
            % Finalize API URL
            app.APIURL.Value = [char(app.APIURL.Value) '&headersOnly=true'];

            %
            % Search results
            %
            api_url = char(app.APIURL.Value);
            options = weboptions('Timeout', 60);
            api_json = webread(api_url,options);
            study_names = {api_json.study.studyName};
            app.SearchResults.Items=study_names;

            %
            % Update map
            %
            cla(app.map_axes);
            land = readgeotable("landareas.shp");
            app.map_axes = mapaxes(app.MapAxes,NextPlot="add",Color=[1 1 1]);
            geoplot(app.map_axes,land,FaceColor=[0.85 0.85 0.85],EdgeColor=[0.13 0.13 0.13]);
            app.map_axes.ProjectedCRS = app.RobinsonProjectedCRS;

            % Get full header data
            api_full_url = api_url;
            api_full_url(end-16:end) = []; % Deletes &headersOnly=true
            app.api_full_json = webread(api_full_url,options);
            % Initialize latitude & longitude vectors
            lats = [];
            lons = []; 
            % Loop through sites
            num_studies = length(app.api_full_json.study);  % Count number of studies returned
            study_list = app.api_full_json.study;  % Extract list of studies returned
            for i = 1:num_studies  % Loop through studies
                num_sites = length(study_list(i).site);  % Count number of sites in the study
                for j = 1:num_sites  % Loop through sites within the study
                    % Extract latitude, convert to double, and append to vector
                    if isempty(study_list(i).site(j).geo)
                        continue
                    end
                    lat = study_list(i).site(j).geo.geometry.coordinates(1);
                    lon = study_list(i).site(j).geo.geometry.coordinates(2);
                    lats = [lats; str2double(lat{1})];
                    lons = [lons; str2double(lon{1})];
                end
            end

            % Add scatter points to distribution map
            C = [214 144 114]./255;
            gs=geoscatter(app.map_axes,lats,lons);
            gs.MarkerFaceColor=C;
            gs.MarkerEdgeColor='k';
            gs.SizeData=15;
            %{
              I have not decided whether I like MapLimits being edited or
              or not. If so, un-comment the following line:
            %}
            % geolimits(app.map_axes,[latMin latMax],[lonMin lonMax])

            % Add scatter points to the study location map
            % First study in list only
            number_of_sites = length(app.api_full_json.study(1).site);
            if number_of_sites==1
                lat=str2double(app.api_full_json.study(idx).site(1).geo.geometry.coordinates(1));
                lon=str2double(app.api_full_json.study(idx).site(1).geo.geometry.coordinates(2));
            elseif number_of_sites > 1
                lat=[];
                lon=[];
                for latitude = 1:number_of_sites
                for longitude= 1:number_of_sites
                   lat_val = str2double(app.api_full_json.study(idx).site(latitude).geo.geometry.coordinates(1));
                   lon_val = str2double(app.api_full_json.study(idx).site(longitude).geo.geometry.coordinates(2));
                   lat = [lat;lat_val];
                   lon = [lon;lon_val];
                end
                end
            end
             gs=geoscatter(app.map_axes2,lat,lon);
             gs.MarkerFaceColor=C;
             gs.MarkerEdgeColor='k';
             gs.SizeData=30;

            %
            % Update Originator, Citation, and Study Page details
            %
            originator_selection = app.SearchResults.Value;
            idx = find(strcmp(app.SearchResults.Items, originator_selection));
            originator = app.api_full_json.study(idx).investigators;
            app.OriginatorTextArea.Value=originator;
            if isempty(app.api_full_json.study(idx).publication)
                app.CitationTextArea.Value='No citation found';
            else
                citation = app.api_full_json.study(idx).publication.citation;
                app.CitationTextArea.Value=citation;
            end
            studypage_base = 'https://www.ncei.noaa.gov/access/paleo-search/study/';
            studyId = app.api_full_json.study(idx).NOAAStudyId;
            app.StudyPageTextArea.Value = [studypage_base studyId];

            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            user_selection = questdlg('Are you sure you want to clear query parameters?',...
                'Clear','Yes','Cancel','Cancel');
            switch user_selection
                case 'Yes'
                    app.APIURL.Value = app.api_base;
                    latMin = -90;
                    latMax = 90;
                    lonMin = -180;
                    lonMax = 180;
                    app.LatMin.Value = latMin;
                    app.LatMax.Value = latMax;
                    app.LonMin.Value = lonMin;
                    app.LonMax.Value = lonMax;
                    %{
                      I have not decided whether I like the MapLimits
                      being edited or not. Un-comment the following line:
                    %}
                    % geolimits(app.map_axes,[latMin latMax],[lonMin lonMax]) 
                case 'Cancel'
                    % Do nothing
            end

        end

        % Value changed function: SearchResults
        function SearchResultsValueChanged(app, event)
            user_selection = app.SearchResults.Value;
            idx = find(strcmp(app.SearchResults.Items, user_selection));
            originator = app.api_full_json.study(idx).investigators;
            app.OriginatorTextArea.Value=originator;
            if isempty(app.api_full_json.study(idx).publication)
                app.CitationTextArea.Value='No citation found';
            else
                citation = app.api_full_json.study(idx).publication.citation;
                app.CitationTextArea.Value=citation;
            end
            studypage_base = 'https://www.ncei.noaa.gov/access/paleo-search/study/';
            studyId = app.api_full_json.study(idx).NOAAStudyId;
            app.StudyPageTextArea.Value = [studypage_base studyId];
            %
            % Add new map axes
            %
            sc = findobj(app.map_axes2,'Type','Scatter');
            if ~isempty(sc)
             [sc.Visible]=deal('off');
            end
            C = [214 144 114]./255;
            number_of_sites = length(app.api_full_json.study(idx).site);
            if number_of_sites == 1
             lat=str2double(app.api_full_json.study(idx).site(1).geo.geometry.coordinates(1));
             lon=str2double(app.api_full_json.study(idx).site(1).geo.geometry.coordinates(2));
            elseif number_of_sites > 1
             lat=[];
             lon=[];
             for latitude = 1:number_of_sites
             for longitude= 1:number_of_sites
                lat_value = str2double(app.api_full_json.study(idx).site(latitude).geo.geometry.coordinates(1));
                lon_value = str2double(app.api_full_json.study(idx).site(longitude).geo.geometry.coordinates(2));
                lat = [lat;lat_value];
                lon = [lon;lon_value];
             end
             end
            end
             gs=geoscatter(app.map_axes2,lat,lon);
             gs.MarkerFaceColor=C;
             gs.MarkerEdgeColor='k';
             gs.SizeData=30;
           
        end

        % Button pushed function: GotostudypageButton
        function GotostudypageButtonPushed(app, event)
            % Get the URL for the study page from the Study Page Text Area
            studypage = app.StudyPageTextArea.Value;
            % Open the URL in the users browser
            web(char(studypage), '-browser');
        end

        % Button pushed function: DataDownloadButton
        function DataDownloadButtonPushed(app, event)

            % Get list of datasets in the Search Results panel
            datasets = app.SearchResults.Items;
            if contains(datasets,'None')
                return
            else
                % Get the selected dataset from the Search Results panel
                selected_dataset = app.SearchResults.Value;
                % Find the index position of the selected dataset
                index_of_selected_dataset = find(contains(datasets,selected_dataset));
    
                % Store the full API variable as "JSON"
                JSON = app.api_full_json;
                % Index into "JSON" to get the selected dataset only
                JSON.study = JSON.study(index_of_selected_dataset);
                
                % Download all of the data files for the selected dataset
                download_study_data(JSON);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.H1BackgroundLabel = uilabel(app.UIFigure);
            app.H1BackgroundLabel.Text = 'H1 Background';
            app.H2BackgroundLabel = uilabel(app.UIFigure);
            app.H2BackgroundLabel.Text = 'H2 Background';
            app.ForegroundLabel = uilabel(app.UIFigure);
            app.ForegroundLabel.Text = 'Foreground';
            app.ButtonLabel = uilabel(app.UIFigure);
            app.ButtonLabel.Text = 'Button';
            app.H3BackgroundLabel = uilabel(app.UIFigure);
            app.H3BackgroundLabel.Text = 'H3 Background';
            app.HighlightLabel = uilabel(app.UIFigure);
            app.HighlightLabel.Text = 'Highlight';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '[ 17  46  81]./255';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '[ 76  76  76]./255';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '[255 255 255]./255';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '[ 36  91 171]./255';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '[229 241 251]./255';
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '[214 144 114]./255';
            app.COLORSLabel = uilabel(app.UIFigure);
            app.COLORSLabel.Text = 'COLORS';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneralSearchTab = uitab(app.TabGroup);
            app.GeneralSearchTab.Title = 'General Search';
            app.Label_APIURL = uilabel(app.GeneralSearchTab);
            app.Label_APIURL.Text = {'API URL for'; 'these parameters'};
            app.Label_DataType = uilabel(app.GeneralSearchTab);
            app.Label_DataType.Text = 'Data Type';
            app.Label_DataTypeTip = uilabel(app.GeneralSearchTab);
            app.Label_DataTypeTip.Text = 'Hold CTRL to select multiple data types.';
            app.DataTypeList = uilistbox(app.GeneralSearchTab);
            app.APIURL = uitextarea(app.GeneralSearchTab);
            app.APIURL.Value = {'https://www.ncei.noaa.gov/access/paleo-search/study/search.json?dataPublisher=NOAA'};
            app.SearchButton = uibutton(app.GeneralSearchTab, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.Label_MinLat = uilabel(app.GeneralSearchTab);
            app.Label_MinLat.Text = 'Min';
            app.Label_MaxLat = uilabel(app.GeneralSearchTab);
            app.Label_MaxLat.Text = 'Max';
            app.LatMin = uieditfield(app.GeneralSearchTab, 'numeric');
            app.LatMin.Limits = [-90 89];
            app.LatMin.Value = -90;
            app.Label_MinLon = uilabel(app.GeneralSearchTab);
            app.Label_MinLon.Text = 'Min';
            app.Label_MaxLon = uilabel(app.GeneralSearchTab);
            app.Label_MaxLon.Text = 'Max';
            app.LonMin = uieditfield(app.GeneralSearchTab, 'numeric');
            app.LonMin.Limits = [-180 179];
            app.LonMin.Value = -180;
            app.Label_Longitude = uilabel(app.GeneralSearchTab);
            app.Label_Longitude.Text = 'Longitude (°E)';
            app.Label_Latitude = uilabel(app.GeneralSearchTab);
            app.Label_Latitude.Text = 'Latitude (°N)';
            app.MapAxes = uipanel(app.GeneralSearchTab);
            app.MapAxes.Title = 'Study Distribution';
            app.LatMax = uieditfield(app.GeneralSearchTab, 'numeric');
            app.LatMax.Limits = [-89 90];
            app.LatMax.Value = 90;
            app.LonMax = uieditfield(app.GeneralSearchTab, 'numeric');
            app.LonMax.Limits = [-179 180];
            app.LonMax.Value = 180;
            app.ClearButton = uibutton(app.GeneralSearchTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'C';
            app.Label_DataType_10 = uilabel(app.UIFigure);
            app.Label_DataType_10.Text = '  Search Results';
            app.SearchResults = uilistbox(app.UIFigure);
            app.SearchResults.Items = {'None'};
            app.SearchResults.ValueChangedFcn = createCallbackFcn(app, @SearchResultsValueChanged, true);
            app.SearchResults.Value = 'None';
            app.Label_Originator = uilabel(app.UIFigure);
            app.Label_Originator.Text = 'Originator';
            app.OriginatorTextArea = uitextarea(app.UIFigure);
            app.OriginatorTextArea.Value = {'Originator'};
            app.Label_Citation = uilabel(app.UIFigure);
            app.Label_Citation.Text = 'Citation Information';
            app.CitationTextArea = uitextarea(app.UIFigure);
            app.CitationTextArea.Value = {'Citation'};
            app.Label_StudyPage = uilabel(app.UIFigure);
            app.Label_StudyPage.Text = 'NOAA Study Page';
            app.StudyPageTextArea = uitextarea(app.UIFigure);
            app.StudyPageTextArea.Value = {'Study page'};
            app.GotostudypageButton = uibutton(app.UIFigure, 'push');
            app.GotostudypageButton.ButtonPushedFcn = createCallbackFcn(app, @GotostudypageButtonPushed, true);
            app.GotostudypageButton.Text = 'Go to study page';
            app.StudyLocation = uipanel(app.UIFigure);
            app.DataDownloadButton = uibutton(app.UIFigure, 'push');
            app.DataDownloadButton.ButtonPushedFcn = createCallbackFcn(app, @DataDownloadButtonPushed, true);
            app.DataDownloadButton.Text = 'Data Download';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = paleoDataSearch

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end